classdef Satellite_image_change_recognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SatelliteImageChangeRecognitionUIFigure  matlab.ui.Figure
        Toolbar                      matlab.ui.container.Toolbar
        PushTool                     matlab.ui.container.toolbar.PushTool
        PushTool2                    matlab.ui.container.toolbar.PushTool
        Panel                        matlab.ui.container.Panel
        Image3                       matlab.ui.control.Image
        UploadimagesLabel            matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        TabGroup                     matlab.ui.container.TabGroup
        LoadedPicturesTab            matlab.ui.container.Tab
        DeleteshownpictureLabel      matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        RUNButton                    matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        Switch                       matlab.ui.control.Switch
        timelineSlider               matlab.ui.control.Slider
        Button_2                     matlab.ui.control.Button
        Button                       matlab.ui.control.Button
        FeatureDetectionTab          matlab.ui.container.Tab
        Switch_2                     matlab.ui.control.Switch
        Button_5                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        OverlappingPicturesTab       matlab.ui.container.Tab
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        ChangeRecognitionTab         matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        UIAxes                       matlab.ui.control.UIAxes
        TimeLapseTab                 matlab.ui.container.Tab
        SpeedSlider                  matlab.ui.control.Slider
        SpeedSliderLabel             matlab.ui.control.Label
        ShowchangesCheckBox          matlab.ui.control.CheckBox
        SettingsTab                  matlab.ui.container.Tab
        PeakThresholdEditField       matlab.ui.control.NumericEditField
        PeakThresholdEditFieldLabel  matlab.ui.control.Label
        EdgeThresholdEditField       matlab.ui.control.NumericEditField
        EdgeThresholdEditFieldLabel  matlab.ui.control.Label
        ThresholdMatchingEditField   matlab.ui.control.NumericEditField
        ThresholdMatchingEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        pic;
        index;
        index_feature;
        index_rotated;
        loaded_pictures;
        feature_pictures;
        matched_feature_pictures;
        rotated_pictures;
    end
    
    methods (Access = private)
        
        function change_timeline(app)
            labels=strings(1,4);ticks=zeros(1,4);minorticks=zeros(1,length(app.pic.pic));j=1;
            for i=round(linspace(1,length(app.pic.pic),4))
                ticks(1,j)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
                labels(1,j)=datestr(app.pic.pic{1,i}.date);
                j=j+1;
            end
            for i=1:length(app.pic.pic)
                minorticks(1,i)=year(app.pic.pic{1,i}.date)+month(app.pic.pic{1,i}.date)/12;
            end
            app.timelineSlider.Limits = [ticks(1),ticks(length(ticks))];
            app.timelineSlider.Value = ticks(1);
            app.timelineSlider.MajorTicks = ticks;
            app.timelineSlider.MajorTickLabels = labels;
            app.timelineSlider.MinorTicks = minorticks;
        end
        
        function changed_points = get_changed_points(~,I)
            hsv_matrix = rgb2hsv(I);
            reshaped = reshape(hsv_matrix,[size(hsv_matrix,1)*size(hsv_matrix,2),3]);
            cond = reshaped(:,1)<=0.877 & reshaped(:,1)>=0.804 & reshaped(:,3)<=0.9;
            pink_matrix = reshaped(cond,:);
            max_V=max(pink_matrix(:,3));
            if(isempty(max_V))
                max_V=1;
            end
            [row,col] = find((hsv_matrix(:,:,1)<=0.877 & hsv_matrix(:,:,1)>=0.804 & hsv_matrix(:,:,2)<=0.75 &...
                hsv_matrix(:,:,2)>=0.3 & hsv_matrix(:,:,3)<=max_V & hsv_matrix(:,:,3)>=0.6*max_V) | ...
                (hsv_matrix(:,:,1)<=0.414 & hsv_matrix(:,:,1)>=0.280 & hsv_matrix(:,:,2)<=0.733 &...
                hsv_matrix(:,:,2)>=0.438 & hsv_matrix(:,:,3)<=1 & hsv_matrix(:,:,3)>=0.854));
            changed_points=[col,row];
%             changed_points=zeros(size(I,1)*size(I,2),2);
%             for i=1:size(I,1)
%                 parfor j=1:size(I,2)
%                     if((hsv_matrix(i,j,1)<=0.877 && hsv_matrix(i,j,1)>=0.804 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0.8 && hsv_matrix(i,j,2)<=1 && hsv(i,j,2)>=0) || ...
%                             (hsv_matrix(i,j,1)<=0.414 && hsv_matrix(i,j,1)>=0.280 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0.8 && hsv_matrix(i,j,2)<=1 && hsv_matrix(i,j,2)>=0))
%                         changed_points(i+j,:)=[j,i];
%                     end
%                 end
%             end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            app.Panel.Visible = false;
            dir={};
            if(ispref('difference_detector','picture_dir'))
                dir = {getpref('difference_detector','picture_dir')};
            end
            [filenames,path] = uigetfile({'*','All Files'},'Select the pictures',dir{:},'MultiSelect','on');
            figure(app.SatelliteImageChangeRecognitionUIFigure)
            if(~isequal(filenames,0))
                setpref('difference_detector','picture_dir',path);
                if(~isempty(app.pic))
                    old_pic = app.pic.get_pic;
                else
                    old_pic = [];
                end
                progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Please Wait','Message','Pictures are loading');
                app.pic = pictures(app,filenames,path,old_pic);
                app.loaded_pictures = cell(1,length(app.pic.pic));
                for i=1:length(app.loaded_pictures)
                    app.loaded_pictures{1,i}.Panel = uipanel(app.LoadedPicturesTab);
                    app.loaded_pictures{1,i}.Panel.Position = [62 86 537 325];
                    app.loaded_pictures{1,i}.Panel.BorderType = 'none';
                    app.loaded_pictures{1,i}.UIAxes = uiaxes(app.loaded_pictures{1,i}.Panel);
                    app.loaded_pictures{1,i}.UIAxes.Position = [0 0 app.loaded_pictures{1,i}.Panel.Position(3:4)];
                    disableDefaultInteractivity(app.loaded_pictures{1,i}.UIAxes)
                    app.loaded_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                    title(app.loaded_pictures{1,i}.UIAxes, []);
                    xlabel(app.loaded_pictures{1,i}.UIAxes, []);
                    ylabel(app.loaded_pictures{1,i}.UIAxes, []);
                    app.loaded_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                    app.loaded_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                    app.loaded_pictures{1,i}.UIAxes.Visible = 'off';
                    I = imshow(app.pic.pic{1,i}.picture,'Parent',app.loaded_pictures{1,i}.UIAxes,...
                        'XData',[1 app.loaded_pictures{1,i}.UIAxes.Position(3)],'YData',[1 app.loaded_pictures{1,i}.UIAxes.Position(4)]);
                    app.loaded_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                    app.loaded_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                    app.loaded_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                    if(i==1)
                        app.loaded_pictures{1,i}.Panel.Visible=true;
                        drawnow;
                    else
                        app.loaded_pictures{1,i}.Panel.Visible=false;
                    end
                    progress.Value = i/(length(app.loaded_pictures));
                end
                app.index=1;
                progress.Message = 'Changing Timeline';
                app.change_timeline;
                close(progress);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            for i=1:length(app.loaded_pictures)
                app.loaded_pictures{1,i}.Panel.Visible=false;
            end
            drawnow;
            if(app.index==1)
                app.index = length(app.pic.pic);
            else
                app.index=app.index-1;
            end 
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
            drawnow;
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            for i=1:length(app.loaded_pictures)
                app.loaded_pictures{1,i}.Panel.Visible=false;
            end
            drawnow;
            if(app.index==length(app.pic.pic))
                app.index = 1;
            else
                app.index=app.index+1;
            end 
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
            drawnow;
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            for i=app.index:length(app.pic.pic)-1
                app.pic.pic{1,i}=app.pic.pic{1,i+1};
                app.loaded_pictures{1,i}=app.loaded_pictures{1,i+1};
            end
            app.pic.pic=app.pic.pic(1,1:end-1);
            app.loaded_pictures=app.loaded_pictures(1,1:end-1);
            app.loaded_pictures{1,app.index}.Panel.Visible=false;
            if(app.index==1)
                app.index = length(app.pic.pic);
            else
                app.index=app.index-1;
            end 
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
            app.change_timeline;
            app.timelineSlider.Value=app.timelineSlider.MinorTicks(app.index);
        end

        % Value changed function: timelineSlider
        function timelineSliderValueChanged(app, event)
            value = app.timelineSlider.Value;
            [d,ix] = min(abs(app.timelineSlider.MinorTicks-value));
            app.timelineSlider.Value = app.timelineSlider.MinorTicks(ix);
            app.loaded_pictures{1,app.index}.Panel.Visible=false;
            app.index = ix;
            app.loaded_pictures{1,app.index}.Panel.Visible=true;
        end

        % Clicked callback: PushTool2
        function PushTool2Clicked(app, event)
            progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Deleting all Images','Indeterminate','on');
            app.TabGroup.SelectedTab = app.TabGroup.Children(1,1);
            app.pic=[];
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [];
            app.timelineSlider.MajorTickLabels = {};
            app.loaded_pictures=[];
            app.feature_pictures=[];
            app.matched_feature_pictures=[];
            app.rotated_pictures=[];
            close(progress);
            app.Panel.Visible = true;
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            progress = uiprogressdlg(app.SatelliteImageChangeRecognitionUIFigure,'Title','Please Wait','Message','Change detection is running');
            drawnow;
            app.pic = app.pic.run_change_detection(progress);
            progress.Message = 'Feature Points are plotted';
            progress.Value = 0;
            drawnow;
            app.feature_pictures = cell(1,length(app.pic.pic));
            app.matched_feature_pictures = cell(1,length(app.pic.pic));
            for i=1:length(app.pic.pic)
                %% Feature Panels
                app.feature_pictures{1,i}.Panel = uipanel(app.FeatureDetectionTab);
                app.feature_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.feature_pictures{1,i}.Panel.BorderType = 'none';
                app.feature_pictures{1,i}.UIAxes = uiaxes(app.feature_pictures{1,i}.Panel);
                app.feature_pictures{1,i}.UIAxes.Position = [0 0 app.feature_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.feature_pictures{1,i}.UIAxes)
                app.feature_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.feature_pictures{1,i}.UIAxes, []);
                xlabel(app.feature_pictures{1,i}.UIAxes, []);
                ylabel(app.feature_pictures{1,i}.UIAxes, []);
                app.feature_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.feature_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.feature_pictures{1,i}.UIAxes.Visible = 'off';
                %% Matched Feature Panels
                app.matched_feature_pictures{1,i}.Panel = uipanel(app.FeatureDetectionTab);
                app.matched_feature_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.matched_feature_pictures{1,i}.Panel.BorderType = 'none';
                app.matched_feature_pictures{1,i}.UIAxes = uiaxes(app.matched_feature_pictures{1,i}.Panel);
                app.matched_feature_pictures{1,i}.UIAxes.Position = [0 0 app.matched_feature_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.matched_feature_pictures{1,i}.UIAxes)
                app.matched_feature_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.matched_feature_pictures{1,i}.UIAxes, []);
                xlabel(app.matched_feature_pictures{1,i}.UIAxes, []);
                ylabel(app.matched_feature_pictures{1,i}.UIAxes, []);
                app.matched_feature_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.matched_feature_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.matched_feature_pictures{1,i}.UIAxes.Visible = 'off';
                I = app.pic.pic{1,i}.picture;
                if(app.Switch.Value=="SURF")
                    I = insertMarker(I,app.pic.pic{1,i}.SURF.validPts,'x');
                elseif(app.Switch.Value=="SIFT")
                    I = insertMarker(I,app.pic.pic{1,i}.SIFT.features(1:2,:)','x');
                end
                I=imshow(I,'Parent',app.feature_pictures{1,i}.UIAxes,...
                    'XData',[1 app.feature_pictures{1,i}.UIAxes.Position(3)],'YData',[1 app.feature_pictures{1,i}.UIAxes.Position(4)]);
                app.feature_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.feature_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.feature_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(app.Switch.Value=="SURF")
                    if(i~=length(app.pic.pic))
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,i+1}.picture,...
                            app.pic.pic{1,i}.SURF.filteredmatchedPts{1,1},app.pic.pic{1,i}.SURF.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,i+1}.date)]);
                    else
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,1}.picture,...
                            app.pic.pic{1,i}.SURF.filteredmatchedPts{1,1},app.pic.pic{1,i}.SURF.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,1}.date),"(for the sake of completeness)"]);
                    end
                elseif(app.Switch.Value=="SIFT")
                    if(i~=length(app.pic.pic))
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,i+1}.picture,...
                            app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,1},app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,i+1}.date)]);
                    else
                        IM=showMatchedFeatures(app.pic.pic{1,i}.picture,app.pic.pic{1,1}.picture,...
                            app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,1},app.pic.pic{1,i}.SIFT.filteredmatchedPts{1,2},...
                            'montage','Parent',app.matched_feature_pictures{1,i}.UIAxes);
                        app.matched_feature_pictures{1,i}.UIAxes.Title.String = join([datestr(app.pic.pic{1,i}.date),"to",datestr(app.pic.pic{1,1}.date),"(for the sake of completeness)"]);
                    end
                end
                app.matched_feature_pictures{1,i}.UIAxes.XLim = [0 IM.XData(2)];
                app.matched_feature_pictures{1,i}.UIAxes.YLim = [0 IM.YData(2)];
                if(i==1)
                    if(app.Switch_2.Value=="Feature Points")
                        app.feature_pictures{1,i}.Panel.Visible=true;
                        app.matched_feature_pictures{1,i}.Panel.Visible=false;
                    elseif(app.Switch_2.Value=="Matched Feature Points")
                        app.feature_pictures{1,i}.Panel.Visible=false;
                        app.matched_feature_pictures{1,i}.Panel.Visible=true;
                    end
                    drawnow;
                else
                    app.feature_pictures{1,i}.Panel.Visible=false;
                    app.matched_feature_pictures{1,i}.Panel.Visible=false;
                end
                progress.Value=i/length(app.pic.pic);
            end
            app.index_feature = 1;
            progress.Message = 'Rotated Pictures are plotted';
            progress.Value = 0;
            app.rotated_pictures = cell(1,length(app.pic.pic));
            for i=1:length(app.pic.pic)
                app.rotated_pictures{1,i}.Panel = uipanel(app.OverlappingPicturesTab);
                app.rotated_pictures{1,i}.Panel.Position = [119,24,641,400];
                app.rotated_pictures{1,i}.Panel.BorderType = 'none';
                app.rotated_pictures{1,i}.UIAxes = uiaxes(app.rotated_pictures{1,i}.Panel);
                app.rotated_pictures{1,i}.UIAxes.Position = [0 0 app.rotated_pictures{1,i}.Panel.Position(3:4)];
                disableDefaultInteractivity(app.rotated_pictures{1,i}.UIAxes)
                app.rotated_pictures{1,i}.UIAxes.Toolbar.Visible = 'off'; 
                title(app.rotated_pictures{1,i}.UIAxes, []);
                xlabel(app.rotated_pictures{1,i}.UIAxes, []);
                ylabel(app.rotated_pictures{1,i}.UIAxes, []);
                app.rotated_pictures{1,i}.UIAxes.XAxis.TickLabels = {};
                app.rotated_pictures{1,i}.UIAxes.YAxis.TickLabels = {};
                app.rotated_pictures{1,i}.UIAxes.Visible = 'off';
                if(app.Switch.Value=="SURF")
                    if(i==1)
                        I = app.pic.pic{1,i}.picture;
                    elseif(i==2)
                        RA = imref2d(size(app.pic.pic{1,i}.picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SURF.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,1}.picture,RA,app.pic.pic{1,i}.SURF.rotated_picture,RB);
                        app.pic.pic{1,i}.SURF.changed_points = app.get_changed_points(I);
%                         save('Image.mat','I')
%                         I = insertMarker(I,app.pic.pic{1,i}.SURF.changed_points,'x');
                    else
                        RA = imref2d(size(app.pic.pic{1,i-1}.SURF.rotated_picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SURF.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,i-1}.SURF.rotated_picture,RA,app.pic.pic{1,i}.SURF.rotated_picture,RB,'falsecolor','Scaling','joint');
                        if(i==4)
                        save('Image.mat','I')
                        end
                        app.pic.pic{1,i}.SURF.changed_points = app.get_changed_points(I);
%                         I = insertMarker(I,app.pic.pic{1,i}.SURF.changed_points,'x');
                    end
                elseif(app.Switch.Value=="SIFT")
                    if(i==1)
                        I = app.pic.pic{1,i}.picture;
                    elseif(i==2)
%                         I = app.pic.pic{1,i}.SIFT.rotated_picture;
                        RA = imref2d(size(app.pic.pic{1,i}.picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SIFT.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,1}.picture,RA,app.pic.pic{1,i}.SIFT.rotated_picture,RB);
                    else
%                         I = app.pic.pic{1,i}.SIFT.rotated_picture;
                        RA = imref2d(size(app.pic.pic{1,i-1}.SIFT.rotated_picture));
                        RB = imref2d(size(app.pic.pic{1,i}.SIFT.rotated_picture));
                        RB.XWorldLimits = RA.XWorldLimits;
                        RB.YWorldLimits = RA.YWorldLimits;
                        I = imfuse(app.pic.pic{1,i-1}.SIFT.rotated_picture,RA,app.pic.pic{1,i}.SIFT.rotated_picture,RB,'falsecolor','Scaling','joint');
                    end
                end
                I = imshow(I,'Parent',app.rotated_pictures{1,i}.UIAxes);
                I.XData = [1 app.rotated_pictures{1,i}.UIAxes.Position(3)];
                I.YData = [1 app.rotated_pictures{1,i}.UIAxes.Position(4)];
                app.rotated_pictures{1,i}.UIAxes.XLim = [0 I.XData(2)];
                app.rotated_pictures{1,i}.UIAxes.YLim = [0 I.YData(2)];
                app.rotated_pictures{1,i}.UIAxes.Title.String = datestr(app.pic.pic{1,i}.date);
                if(i==1)
                    app.rotated_pictures{1,i}.Panel.Visible=true;
                    drawnow;
                else
                    app.rotated_pictures{1,i}.Panel.Visible=false;
                end
                progress.Value = i/length(app.pic.pic);
            end 
            app.index_rotated = 1;
            app.pic.pic = showChanges(app.pic.pic);
            close(progress);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            for i=1:length(app.pic.pic)
                app.feature_pictures{1,i}.Panel.Visible=false;
                app.matched_feature_pictures{1,i}.Panel.Visible=false;
            end
            if(value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.feature_pictures{1,app.index_feature}.Panel.Visible=false;
            app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=false;
            if(app.index_feature==length(app.pic.pic))
                app.index_feature = 1;
            else
                app.index_feature=app.index_feature+1;
            end
            if(app.Switch_2.Value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(app.Switch_2.Value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.feature_pictures{1,app.index_feature}.Panel.Visible=false;
            app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=false;
            drawnow;
            if(app.index_feature==1)
                app.index_feature = length(app.pic.pic);
            else
                app.index_feature=app.index_feature-1;
            end 
            if(app.Switch_2.Value=="Feature Points")
                app.feature_pictures{1,app.index_feature}.Panel.Visible=true;
            elseif(app.Switch_2.Value=="Matched Feature Points")
                app.matched_feature_pictures{1,app.index_feature}.Panel.Visible=true;
            end
            drawnow;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=false;
            if(app.index_rotated==length(app.pic.pic))
                app.index_rotated = 1;
            else
                app.index_rotated=app.index_rotated+1;
            end
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=true;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=false;
            if(app.index_rotated==1)
                app.index_rotated = length(app.pic.pic);
            else
                app.index_rotated=app.index_rotated-1;
            end
            app.rotated_pictures{1,app.index_rotated}.Panel.Visible=true;
        end

        % Image clicked function: Image3
        function Image3Clicked(app, event)
            app.PushToolClicked(event);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if(selectedTab.Title~="Loaded Pictures" && isempty(app.pic.pic{1,1}.SURF) && isempty(app.pic.pic{1,1}.SIFT))
                app.TabGroup.SelectedTab = app.TabGroup.Children(1,1);
                uialert(app.SatelliteImageChangeRecognitionUIFigure,"Difference detection must have been performed. Please click the RUN-Button.","Run Change Detection");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SatelliteImageChangeRecognitionUIFigure = uifigure('Visible', 'off');
            app.SatelliteImageChangeRecognitionUIFigure.Name = 'Satellite Image Change Recognition';
            app.SatelliteImageChangeRecognitionUIFigure.Icon = 'Team_Logo.png';
            app.Toolbar = uitoolbar(app.SatelliteImageChangeRecognitionUIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = '126477.png';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.TabGroup = uitabgroup(app.SatelliteImageChangeRecognitionUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.LoadedPicturesTab = uitab(app.TabGroup);
            app.LoadedPicturesTab.Title = 'Loaded Pictures';
            app.Button = uibutton(app.LoadedPicturesTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'arrows-left-slider-31125.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.LoadedPicturesTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'arrows-right-slider-31125.png';
            app.Button_2.Text = '';
            app.timelineSlider = uislider(app.LoadedPicturesTab);
            app.timelineSlider.Limits = [0 10];
            app.timelineSlider.MajorTicks = [];
            app.timelineSlider.MajorTickLabels = {''};
            app.timelineSlider.ValueChangedFcn = createCallbackFcn(app, @timelineSliderValueChanged, true);
            app.timelineSlider.MinorTicks = [];
            app.Switch = uiswitch(app.LoadedPicturesTab, 'slider');
            app.Switch.Items = {'SURF', 'SIFT'};
            app.Switch.Value = 'SURF';
            app.TextArea = uitextarea(app.LoadedPicturesTab);
            app.TextArea.Value = {'Advantages:'; ''; ''; 'Disadvantages:'};
            app.RUNButton = uibutton(app.LoadedPicturesTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Button_3 = uibutton(app.LoadedPicturesTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'png-transparent-rubbish-bins-waste-paper-baskets-computer-icons-r.png';
            app.Button_3.Text = '';
            app.DeleteshownpictureLabel = uilabel(app.LoadedPicturesTab);
            app.DeleteshownpictureLabel.Text = 'Delete shown picture';
            app.FeatureDetectionTab = uitab(app.TabGroup);
            app.FeatureDetectionTab.Title = 'Feature Detection';
            app.Button_4 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'arrows-right-slider-31125.png';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.FeatureDetectionTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'arrows-left-slider-31125.png';
            app.Button_5.Text = '';
            app.Switch_2 = uiswitch(app.FeatureDetectionTab, 'slider');
            app.Switch_2.Items = {'Feature Points', 'Matched Feature Points'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Feature Points';
            app.OverlappingPicturesTab = uitab(app.TabGroup);
            app.OverlappingPicturesTab.Title = 'Overlapping Pictures';
            app.Button_6 = uibutton(app.OverlappingPicturesTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'arrows-right-slider-31125.png';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.OverlappingPicturesTab, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'arrows-left-slider-31125.png';
            app.Button_7.Text = '';
            app.ChangeRecognitionTab = uitab(app.TabGroup);
            app.ChangeRecognitionTab.Title = 'Change Recognition';
            app.UIAxes = uiaxes(app.ChangeRecognitionTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.ChangeRecognitionTab);
            app.TimeLapseTab = uitab(app.TabGroup);
            app.TimeLapseTab.Title = 'Time Lapse';
            app.ShowchangesCheckBox = uicheckbox(app.TimeLapseTab);
            app.ShowchangesCheckBox.Text = '  Show changes';
            app.SpeedSliderLabel = uilabel(app.TimeLapseTab);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.TimeLapseTab);
            app.SpeedSlider.MajorTicks = [];
            app.SpeedSlider.MinorTicks = [];
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ThresholdMatchingEditFieldLabel = uilabel(app.SettingsTab);
            app.ThresholdMatchingEditFieldLabel.Text = 'Threshold Matching';
            app.ThresholdMatchingEditField = uieditfield(app.SettingsTab, 'numeric');
            app.ThresholdMatchingEditField.Value = 1.5;
            app.EdgeThresholdEditFieldLabel = uilabel(app.SettingsTab);
            app.EdgeThresholdEditFieldLabel.Text = 'Edge Threshold';
            app.EdgeThresholdEditField = uieditfield(app.SettingsTab, 'numeric');
            app.EdgeThresholdEditField.Value = 10;
            app.PeakThresholdEditFieldLabel = uilabel(app.SettingsTab);
            app.PeakThresholdEditFieldLabel.Text = 'Peak Threshold';
            app.PeakThresholdEditField = uieditfield(app.SettingsTab, 'numeric');
            app.Panel = uipanel(app.SatelliteImageChangeRecognitionUIFigure);
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'Team_Logo.png';
            app.UploadimagesLabel = uilabel(app.Panel);
            app.UploadimagesLabel.Text = 'Upload images';
            app.Image3 = uiimage(app.Panel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @Image3Clicked, true);
            app.Image3.ImageSource = '126477.png';
            % Show the figure after all components are created
            app.SatelliteImageChangeRecognitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Satellite_image_change_recognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SatelliteImageChangeRecognitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SatelliteImageChangeRecognitionUIFigure)
        end
    end
end